classdef plot_montage_data < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        selectdataTab             matlab.ui.container.Tab
        loaddataButton            matlab.ui.control.Button
        saveidxsButton            matlab.ui.control.Button
        dataplotsPanel            matlab.ui.container.Panel
        loadidxsButton            matlab.ui.control.Button
        montagefileDropDownLabel  matlab.ui.control.Label
        montagefileDropDown       matlab.ui.control.DropDown
        zoomButton                matlab.ui.control.Button
        zoomcurrentButton         matlab.ui.control.Button
        plotpsdsButton            matlab.ui.control.Button
        exportrawdataButton       matlab.ui.control.Button
        popoutButton_4            matlab.ui.control.Button
        PSDSTNTab                 matlab.ui.container.Tab
        PlotdataPanelSTN          matlab.ui.container.Panel
        montageListBox_2Label     matlab.ui.control.Label
        psdMontageSelector        matlab.ui.control.ListBox
        saveFigurePsdSTN          matlab.ui.control.Button
        EditFieldLabel            matlab.ui.control.Label
        EditField                 matlab.ui.control.EditField
        xminEditFieldLabel        matlab.ui.control.Label
        xminEditField             matlab.ui.control.NumericEditField
        xmaxEditFieldLabel        matlab.ui.control.Label
        xmaxEditField             matlab.ui.control.NumericEditField
        popOutPSDstn              matlab.ui.control.Button
        PSDM1Tab                  matlab.ui.container.Tab
        PlotdataPanelM1           matlab.ui.container.Panel
        montageListBoxLabel       matlab.ui.control.Label
        m1MontageSelector         matlab.ui.control.ListBox
        saveFigurePSDM1           matlab.ui.control.Button
        EditField_2Label          matlab.ui.control.Label
        EditField_2               matlab.ui.control.EditField
        xminEditField_2Label      matlab.ui.control.Label
        xminEditField_2           matlab.ui.control.NumericEditField
        xmaxEditField_2Label      matlab.ui.control.Label
        xmaxEditField_2           matlab.ui.control.NumericEditField
        popOutPSDM1               matlab.ui.control.Button
        CoherenceTab              matlab.ui.container.Tab
        hCoherenceDataPanel       matlab.ui.container.Panel
        hSaveFigureCoherence      matlab.ui.control.Button
        configfilesLabel          matlab.ui.control.Label
        hCoherenceMontageSelecor  matlab.ui.control.ListBox
        EditField_3Label          matlab.ui.control.Label
        hCoherenceFigreName       matlab.ui.control.EditField
        hCoherencePopOut          matlab.ui.control.Button
        hPlotcoherenceButton      matlab.ui.control.Button
        PACwithinGPSTNleadTab     matlab.ui.container.Tab
        PlotdataPanelM1_3         matlab.ui.container.Panel
        LFPcontactLabel           matlab.ui.control.Label
        m1MontageSelector_3       matlab.ui.control.ListBox
        m1contactLabel            matlab.ui.control.Label
        m1MontageSelector_4       matlab.ui.control.ListBox
        plotButton                matlab.ui.control.Button
        popoutButton_5            matlab.ui.control.Button
        saveButton                matlab.ui.control.Button
        PACwnM1Tab                matlab.ui.container.Tab
        PACbtwndeepecogTab        matlab.ui.container.Tab
        PAC1000hzTab              matlab.ui.container.Tab
    end

    
    properties (Access = public)
        hCurLine % handle to current line
        hCurLineStatus % handle to current line stuats
        
        prFigParams % parameters to print a figure
        dataDir % Description
        ets % events table start
        ete % events table end
        hpltStart % handles for start plot
        hpltEnd % handles for end plot
        hsub % handles for sub plots
        subTime % to change datetime to duration
        outdatcomplete % table of time domain data
        rawDatSTN % raw data for stn
        rawDatM1 % raw data for m1
        hStn % handles for stn psd
        hM1 % handles for m1 psds
        hpltSTNlines % handles to stn psd lines
        hpltM1lines % handles to m1 psd lines
        outRec % chanel data 
        outdatcompleteAcc % actigraphy data 
        powerTable % power data 
        outDataChunks1 = table();
        outDataChunks2 = table();
        outDataChunks3 = table();
        outDataChunks4 = table();
        outDataChunks5 = table();

        outDataChunksChan % structue to save the chunk channel 

        hsubCoherence % Description
    end
    
    methods (Access = public)
        
        function LineDown(app,event,src)
            fprintf('line down cur line status %d\n',app.hCurLineStatus);
            app.hCurLine = event;
            app.hCurLineStatus = 1;
        end
        
        function MouseMove(app,event,src)
            if app.hCurLineStatus
                fprintf('mouse move cur line status %d\n',app.hCurLineStatus);
                hUiaxes = get(app.hCurLine,'Parent');
                cp = get ( hUiaxes, 'CurrentPoint' );
                set ( app.hCurLine, 'XData', [cp(1,1) cp(1,1)] );
            end
        end
        
        function MouseUp(app,event,src)
            fprintf('mouse up cur line status %d\n',app.hCurLineStatus);
            app.hCurLineStatus = 0;
        end
        
        function results = plotCoherenceData(app)
            results = [];
            % set up subplots
            delete(app.hCoherenceDataPanel.Children)
            hsub(1) = subplot(2,3,1,'Parent',app.hCoherenceDataPanel);
            hsub(2) = subplot(2,3,4,'Parent',app.hCoherenceDataPanel);
            hsub(3) = subplot(2,3,2,'Parent',app.hCoherenceDataPanel);
            hsub(4) = subplot(2,3,5,'Parent',app.hCoherenceDataPanel);
            hsub(5) = subplot(2,3,3,'Parent',app.hCoherenceDataPanel);
            hsub(6) = subplot(2,3,6,'Parent',app.hCoherenceDataPanel);
            app.hsubCoherence = gobjects(6,1); 
            for i = 1:6
                app.hsubCoherence(i) = hsub(i);
            end
            
            cnPairs = [1 2;... % stn stn
                3 4;... % m1 m1
                1 3;... % m1 m1
                1 4;...
                2 3;...
                2 4];
            idxMontage = find(strcmp(app.hCoherenceMontageSelecor.Value,app.hCoherenceMontageSelecor.Items)==1);
            
            outdatcomplete = app.(sprintf('outDataChunks%d',idxMontage));
            outRec = app.outRec(idxMontage);
            times = outdatcomplete.derivedTimes;
            srate = unique( outdatcomplete.samplerate );
            nmplt = 1;
            i = 1;
            for c = 1:size(cnPairs,1)
                axes(hsub(c));
                cla(hsub(c))
                hold on;
                % first channel
                cIdx1 = cnPairs(c,1);
                fnm = sprintf('key%d',cIdx1-1);
                y1 = outdatcomplete.(fnm);
                y1 = y1 - mean(y1);
                
                cIdx2 = cnPairs(c,2);
                fnm = sprintf('key%d',cIdx2-1);
                y2 = outdatcomplete.(fnm);
                y2 = y2 - mean(y2);
                
                %% plot cohenece
                Fs = unique(outdatcomplete.samplerate);
                [Cxy,F] = mscohere(y1',y2',...
                    2^(nextpow2(Fs)),...
                    2^(nextpow2(Fs/2)),...
                    2^(nextpow2(Fs)),...
                    Fs);
                idxplot = F > 0 & F < 100;
                hplot = plot(F(idxplot),Cxy(idxplot),'Parent',hsub(c));
                xlabel(hsub(c),'Freq (Hz)');
                ylabel(hsub(c),'MS Coherence');
                
                xlim(hsub(c),[0 100]);
                ttlGraph = sprintf('C between %s and %s',...
                    outRec(1).tdData(cIdx1).chanOut,...
                    outRec(1).tdData(cIdx2).chanOut);
                set(hsub(c),'FontSize',10);
                
                title(hsub(c),ttlGraph,'FontSize',10);
                clear y1 y2 cIdx1 cIdx2;
                ylims(c,:) = hsub(c).YLim;
                nmplt = nmplt + 1;
            end
            ylimsUse = [min(ylims(:,1)) max(ylims(:,2))];
            for c = 1:6
                set(hsub(c),'Ylim',ylimsUse);
            end
            
        end
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if ~isempty(varargin)
                app.dataDir = varargin{1};
                loaddataButtonPushed(app)
            end
        end

        % Button pushed function: loaddataButton
        function loaddataButtonPushed(app, event)
            % register callback move lines
            set(app.UIFigure,'WindowButtonMotion',@app.MouseMove);
            set(app.UIFigure,'WindowButtonUpFcn',@app.MouseUp);
            
            
            if ~isempty(app.dataDir)
                app.dataDir = app.dataDir;
            else
                app.dataDir= uigetdir();
            end
            
            
            % set up parametes to print a figure
            app.prFigParams.figdir  = app.dataDir;
            app.prFigParams.figtype = '-djpeg';
            app.prFigParams.resolution = 300;
            app.prFigParams.closeafterprint = 1;
            app.prFigParams.plotwidth = 17;
            app.prFigParams.plotheight = 10;
            
            [app.outdatcomplete,app.outRec,eventTable,app.outdatcompleteAcc,app.powerTable] =  MAIN_load_rcs_data_from_folder(app.dataDir);
            outdatcomplete = app.outdatcomplete;
            outRec = app.outRec;
            
            outdatcomplete.derivedTimes(1)
            [fnn,pnn] = fileparts(app.dataDir);
            [fnn,sessionName] = fileparts(fnn);
            figTitle = sprintf('%s %s',outdatcomplete.derivedTimes(1),sessionName);
            app.UIFigure.Name = figTitle;
            
            deviceSettingsFn = fullfile(app.dataDir,'DeviceSettings.json');
            outRec = loadDeviceSettingsForMontage(deviceSettingsFn);
            % figure out add / subtract factor for event times (if pc clock is not same
            % as INS time).
            idxTimeCompare = find(outdatcomplete.PacketRxUnixTime~=0,1);
            packRxTimeRaw  = outdatcomplete.PacketRxUnixTime(idxTimeCompare);
            packtRxTime    =  datetime(packRxTimeRaw/1000,...
                'ConvertFrom','posixTime','TimeZone','America/Los_Angeles','Format','dd-MMM-yyyy HH:mm:ss.SSS');
            derivedTime    = outdatcomplete.derivedTimes(idxTimeCompare);
            timeDiff       = derivedTime - packtRxTime;
            
            
            secs = outdatcomplete.derivedTimes;
            app.subTime = secs(1);
            % find start events
            idxStart = cellfun(@(x) any(strfind(x,'Start')),eventTable.EventType);
            idxEnd = cellfun(@(x) any(strfind(x,'Stop')),eventTable.EventType);
            % populdate controller
            app.montagefileDropDown.Items = eventTable.EventType(idxStart);
            app.montagefileDropDown.ItemsData= 1:sum(idxStart);
            app.montagefileDropDown.Value = 1;
            % plot data
            app.hsub = gobjects(4);
            for c = 1:4
                app.hsub(c) = subplot(4,1,c,'Parent',app.dataplotsPanel);
                hold(app.hsub(c),'on');
                cfnm = sprintf('key%d',c-1);
                y = outdatcomplete.(cfnm);
                secsUse = secs;
                plot(secsUse,y,'Parent',app.hsub(c));
                title(cfnm,'Parent',app.hsub(c));
            end
            linkaxes(app.hsub,'x');
            % insert event table markers and link them
            app.ets = eventTable(idxStart,:);
            app.ete = eventTable(idxEnd,:);
            app.hpltStart = gobjects(sum(idxStart),4);
            app.hpltEnd = gobjects(sum(idxStart),4);
            % plot the lines for event markers
            deltaUse = seconds(5);
            for i = 1:sum(idxStart)
                for c = 1:4
                    hsub = get(app.hsub(c));
                    ylims = hsub.YLim;
                    % start
                    xval = app.ets.UnixOffsetTime(i) + timeDiff +  deltaUse;
                    startTime = xval ;% XXX
                    hplt = plot([xval xval],ylims,'Parent',app.hsub(c),'Color',[0 0.8 0 0.7],'LineWidth',3);
                    app.hpltStart(i,c) = hplt;
                    hplt.ButtonDownFcn = @app.LineDown;
                    % end
                    xval = app.ete.UnixOffsetTime(i)+timeDiff - deltaUse;
                    endTime = xval;
                    hplt = plot([xval xval],ylims,'Parent',app.hsub(c),'Color',[0.8 0 0 0.7],'LineWidth',3);
                    app.hpltEnd(i,c) = hplt;
                    hplt.ButtonDownFcn = @app.LineDown;
                end
            end
            
            % save the raw data
            cntStn = 1;
            cntM1 = 1;
            for i = 1:sum(idxStart)
                % if you have disabled channels assume for now that channels need to be averaged
                if sum(cellfun(@(x) any(strfind(x,'disabled')),{outRec(i).tdData.chanFullStr}))
                    % start
                    xval = app.ets.UnixOffsetTime(i) + timeDiff +  deltaUse;
                    startTime = xval ;% XXX
                    % end
                    xval = app.ete.UnixOffsetTime(i)+timeDiff - deltaUse;
                    endTime = xval;
                    % get raw data from non disableed channels
                    idxNotDisabeled = find(~cellfun(@(x) any(strfind(x,'disabled')),{outRec(i).tdData.chanFullStr})==1);
                    y = [];
                    for c = 1:length(idxNotDisabeled)
                        cfnm = sprintf('key%d',idxNotDisabeled(c)-1);
                        y(:,c) = outdatcomplete.(cfnm)';
                    end
                    secsUse = secs;
                    idxuse = secsUse > startTime & secsUse < endTime;
                    % get sample rate
                    sr = str2num(strrep(outRec(i).tdData(idxNotDisabeled(1)).sampleRate,'Hz',''));
                    c = idxNotDisabeled(1);
                    if min(idxNotDisabeled)<=2 % its stn
                        % save raw data in order to plot psds
                        app.rawDatSTN(cntStn).rawdata = mean(y(idxuse,:),2);
                        app.rawDatSTN(cntStn).sr = sr;
                        app.rawDatSTN(cntStn).chan = sprintf('+%s-%s',outRec(i).tdData(c).plusInput,outRec(i).tdData(c).minusInput);
                        app.rawDatSTN(cntStn).chanFullStr = outRec(i).tdData(idxNotDisabeled(1)).chanFullStr;
                        cntStn = cntStn + 1;
                    else % its m1
                        % save raw data in order to plot psds
                        app.rawDatM1(cntM1).rawdata = y(idxuse);
                        app.rawDatM1(cntM1).sr = sr;
                        app.rawDatM1(cntM1).chan = sprintf('+%s-%s',outRec(i).tdData(c).plusInput,outRec(i).tdData(c).minusInput);
                        app.rawDatM1(cntM1).chanFullStr = outRec(i).tdData(idxNotDisabeled(1)).chanFullStr;
                        cntM1 = cntM1 + 1;
                    end
                    % if you have no disabled channels - so every td channel is
                    % recording
                else % no disabled channels
                    for c = 1:4
                        % start
                        xval = app.ets.UnixOffsetTime(i) + timeDiff +  deltaUse;
                        startTime = xval ;% XXX
                        % end
                        xval = app.ete.UnixOffsetTime(i)+timeDiff - deltaUse;
                        endTime = xval;
                        % get raw data
                        cfnm = sprintf('key%d',c-1);
                        y = outdatcomplete.(cfnm);
                        secsUse = secs;
                        idxuse = secsUse > startTime & secsUse < endTime;
                        % get sample rate
                        sr = str2num(strrep(outRec(i).tdData(c).sampleRate,'Hz',''));
                        if c <=2
                            % save raw data in order to plot psds
                            app.rawDatSTN(cntStn).rawdata = y(idxuse);
                            app.rawDatSTN(cntStn).sr = sr;
                            app.rawDatSTN(cntStn).chan = sprintf('+%s-%s',outRec(i).tdData(c).plusInput,outRec(i).tdData(c).minusInput);
                            app.rawDatSTN(cntStn).chanFullStr = outRec(i).tdData(c).chanFullStr;
                            cntStn = cntStn + 1;
                        else
                            % save raw data in order to plot psds
                            app.rawDatM1(cntM1).rawdata = y(idxuse);
                            app.rawDatM1(cntM1).sr = sr;
                            app.rawDatM1(cntM1).chan = sprintf('+%s-%s',outRec(i).tdData(c).plusInput,outRec(i).tdData(c).minusInput);
                            app.rawDatM1(cntM1).chanFullStr = outRec(i).tdData(c).chanFullStr;
                            cntM1 = cntM1 + 1;
                        end
                    end
                end
            end
            
        end

        % Button pushed function: zoomButton
        function zoomButtonPushed(app, event)
            idx = app.montagefileDropDown.Value;
            hsub = get(app.hsub(1));
            xstart = (app.ets.UnixOffsetTime(idx) - app.subTime) - seconds(5);
            xend = (app.ete.UnixOffsetTime(idx) - app.subTime) + seconds(5);
            hsubs = app.dataplotsPanel.Children;
            hsubs(1).XLim = [xstart xend];
            
        end

        % Button pushed function: zoomcurrentButton
        function zoomcurrentButtonPushed(app, event)
            idx = app.montagefileDropDown.Value;
            xlims = [app.hpltStart(idx,1).XData(1) - seconds(5) app.hpltEnd(idx,1).XData(1) + seconds(5)];
            hsubs = app.dataplotsPanel.Children;
            hsubs(1).XLim = xlims;
            for h = 1:length(hsubs)
                lines = get(hsubs(h),'Children');
                hLine = lines(end);
                idxuse = hLine.XData > xlims(1) & hLine.XData < xlims(2);
                miny = min(hLine.YData(idxuse));
                maxy = max(hLine.YData(idxuse));
                hsubs(h).YLim = [miny maxy];
            end
        end

        % Button pushed function: plotpsdsButton
        function plotpsdsButtonPushed(app, event)
            
            % plot data in the psd stn
            delete(app.hStn)
            app.hStn = gobjects(1,1);
            app.hStn(1) = subplot(1,1,1,'Parent',app.PlotdataPanelSTN);
            hold(app.hStn(1),'on')
            app.hpltSTNlines = gobjects(length(app.rawDatSTN),1);
            for m = 1:length(app.rawDatSTN)
                y = app.rawDatSTN(m).rawdata - mean(app.rawDatSTN(m).rawdata);
                srate = app.rawDatSTN(m).sr;
                [fftOut,f]   = pwelch(y,srate,srate/2,0:1:srate/2,srate,'psd');
                app.hpltSTNlines(m) = plot(f,log10(fftOut),'LineWidth',3,'Parent',app.hStn);
            end
            legend(app.hpltSTNlines,{app.rawDatSTN.chan});
            % populdate controller
            app.psdMontageSelector.Items = {app.rawDatSTN.chan};
            app.psdMontageSelector.ItemsData= 1:length({app.rawDatSTN.chan});
            app.psdMontageSelector.Multiselect = 'on';
            app.psdMontageSelector.Value = 1:length({app.rawDatSTN.chan});
            set(app.hStn(1),'FontSize',16);
            
            % plot data in the psd m1
            delete(app.hM1)
            app.hM1 = gobjects(1,1);
            app.hM1(1) = subplot(1,1,1,'Parent',app.PlotdataPanelM1);
            hold(app.hM1(1),'on')
            app.hpltM1lines = gobjects(length(app.hpltM1lines),1);
            for m = 1:length(app.rawDatSTN)
                y = app.rawDatM1(m).rawdata - mean(app.rawDatM1(m).rawdata);
                srate = app.rawDatM1(m).sr;
                [fftOut,f]   = pwelch(y,srate,srate/2,0:1:srate/2,srate,'psd');
                app.hpltM1lines(m) = plot(f,log10(fftOut),'LineWidth',3,'Parent',app.hM1);
            end
            legend(app.hpltM1lines,{app.rawDatM1.chan});
            % populdate controller
            app.m1MontageSelector.Items = {app.rawDatM1.chan};
            app.m1MontageSelector.ItemsData= 1:length({app.rawDatM1.chan});
            app.m1MontageSelector.Multiselect = 'on';
            app.m1MontageSelector.Value = 1:length({app.rawDatM1.chan});
            
            set(app.hM1(1),'FontSize',16);
        end

        % Value changed function: psdMontageSelector
        function psdMontageSelectorValueChanged(app, event)
            value = app.psdMontageSelector.Value;
            hplt = get(app.hpltSTNlines);
            for h = 1:length(hplt)
                set(app.hpltSTNlines(h),'Visible','off')
            end
            for v = 1:length(value)
                set(app.hpltSTNlines(value(v)),'Visible','on')
            end
            legtext = {app.rawDatSTN.chan};
            legend(app.hpltSTNlines(value),legtext(value));
        end

        % Value changed function: m1MontageSelector
        function m1MontageSelectorValueChanged(app, event)
            value = app.m1MontageSelector.Value;
            hplt = get(app.hpltM1lines);
            for h = 1:length(hplt)
                set(app.hpltM1lines(h),'Visible','off')
            end
            for v = 1:length(value)
                set(app.hpltM1lines(value(v)),'Visible','on')
            end
            legtext = {app.rawDatM1.chan};
            legend(app.hpltM1lines(value),legtext(value));
        end

        % Button pushed function: exportrawdataButton
        function exportrawdataButtonPushed(app, event)
            fnmsv = fullfile(app.dataDir,'rawMontageData');
            montageData.LFP = app.rawDatSTN;
            montageData.M1 = app.rawDatM1;
            save(fnmsv,'montageData');
        end

        % Button pushed function: saveFigurePsdSTN
        function saveFigurePsdSTNPushed(app, event)
            % save stn montage figure
            x = 2;
            prfig = app.prFigParams;
            prfig.figname = app.h;
            plot_hfig(app.hStn,prfig);
        end

        % Value changed function: xminEditField
        function xminEditFieldValueChanged(app, event)
            value = app.xminEditField.Value;
            
        end

        % Value changed function: xmaxEditField
        function xmaxEditFieldValueChanged(app, event)
            value = app.xmaxEditField.Value;
            
        end

        % Button pushed function: saveFigurePSDM1
        function saveFigurePSDM1Pushed(app, event)
            
        end

        % Value changed function: xminEditField_2
        function xminEditField_2ValueChanged(app, event)
            value = app.xminEditField_2.Value;
            
        end

        % Value changed function: xmaxEditField_2
        function xmaxEditField_2ValueChanged(app, event)
            value = app.xmaxEditField_2.Value;
            
        end

        % Button pushed function: hSaveFigureCoherence
        function hSaveFigureCoherencePushed(app, event)
            hfig = figure;
            hCopied = gobjects(6,1);
            for h = 1:6
                hsubnew = subplot(2,3,h);
                positionUse = hsubnew.Position;
                hCopied(h) = copyobj(app.hsubCoherence(h,1),hfig);
                hCopied(h).Position = positionUse;
                delete(hsubnew)
                set(gca,'FontSize',16);
            end
            hfig.Color = 'w';
            linkaxes(hCopied,'x');
            prfig = app.prFigParams;
            prfig.figname = app.hCoherenceFigreName.Value;
            plot_hfig(hfig,prfig);

        end

        % Button pushed function: popOutPSDstn
        function popOutPSDstnPushed(app, event)
            hfig = figure;
            hCopied = gobjects(1,1);
            hleg = gobjects(1,1);
            hleg(1) = app.hStn.Legend;
            h = 1;
            hsubnew = subplot(1,1,h);
            positionUse = hsubnew.Position;
            hCopied(h) = copyobj([ app.hStn(h,1) ],hfig);
            hCopied(h).Position = positionUse;
            hLines = app.hpltSTNlines(app.psdMontageSelector.Value);
            delete(hsubnew)
            xlabel(hCopied(h),'Freq (Hz)');
            ylabel(hCopied(h),'Power  (log_1_0\muV^2/Hz)');
            title('stn montage')
            set(hCopied(h),'FontSize',16);
            legend(hCopied(h),hLines,app.hStn.Legend.String)
            hfig.Color = 'w';
            
        end

        % Button pushed function: popOutPSDM1
        function popOutPSDM1Pushed(app, event)
            hfig = figure;
            hCopied = gobjects(1,1);
            hleg = gobjects(1,1);
            hleg(1) = app.hM1.Legend;
            h = 1;
            hsubnew = subplot(1,1,h);
            positionUse = hsubnew.Position;
            hCopied(h) = copyobj([ app.hM1(h,1) ],hfig);
            hCopied(h).Position = positionUse;
            hLines = app.hpltM1lines(app.m1MontageSelector.Value);
            delete(hsubnew)
            xlabel(hCopied(h),'Freq (Hz)');
            ylabel(hCopied(h),'Power  (log_1_0\muV^2/Hz)');
            title('m1 montage')
            set(hCopied(h),'FontSize',16);
            legend(hCopied(h),hLines,app.hM1.Legend.String)
            hfig.Color = 'w';
        end

        % Button pushed function: popoutButton_4
        function popoutButton_4Pushed(app, event)

            hfig = figure;
            hCopied = gobjects(4,1);
            for h = 1:4
                hsubnew = subplot(4,1,h);
                positionUse = hsubnew.Position;
                hCopied(h) = copyobj(app.hsub(h,1),hfig);
                hCopied(h).Position = positionUse;
                delete(hsubnew)
                set(gca,'FontSize',16);
            end
            hfig.Color = 'w';
            linkaxes(hCopied,'x');
        end

        % Button pushed function: hPlotcoherenceButton
        function hPlotcoherenceButtonPushed(app, event)
            % populate the controlrs
            app.hCoherenceMontageSelecor.Items = {'config1','config2','config3'};
            
            
            % get the relevant data chunk
            % figure out add / subtract factor for event times (if pc clock is not same
            % as INS time).
            idxTimeCompare = find(app.outdatcomplete.PacketRxUnixTime~=0,1);
            packRxTimeRaw  = app.outdatcomplete.PacketRxUnixTime(idxTimeCompare);
            packtRxTime    =  datetime(packRxTimeRaw/1000,...
                'ConvertFrom','posixTime','TimeZone','America/Los_Angeles','Format','dd-MMM-yyyy HH:mm:ss.SSS');
            derivedTime    = app.outdatcomplete.derivedTimes(idxTimeCompare);
            timeDiff       = derivedTime - packtRxTime;
            deltaUse = seconds(5);
            
            app.outDataChunks1 = table();
            app.outDataChunks2 = table();
            app.outDataChunks3 = table();
            app.outDataChunks4 = table();
            app.outDataChunks5 = table();
            app.outDataChunksChan = struct();
            for i = 1:5
                % start
                startTime = app.ets.UnixOffsetTime(i) + timeDiff +  deltaUse;
                % end
                endTime = app.ete.UnixOffsetTime(i)+timeDiff - deltaUse;
                
                secsUse = app.outdatcomplete.derivedTimes;
                idxuse = secsUse > startTime & secsUse < endTime;
                app.(sprintf('outDataChunks%d',i)) = app.outdatcomplete(idxuse,:);
            end
            plotCoherenceData(app);
            
        end

        % Value changed function: hCoherenceMontageSelecor
        function hCoherenceMontageSelecorValueChanged(app, event)
            value = app.hCoherenceMontageSelecor.Value;
            plotCoherenceData(app);
        end

        % Button pushed function: hCoherencePopOut
        function hCoherencePopOutButtonPushed(app, event)
            hfig = figure;
            hCopied = gobjects(6,1);
            for h = 1:6
                hsubnew = subplot(2,3,h);
                positionUse = hsubnew.Position;
                hCopied(h) = copyobj(app.hsubCoherence(h,1),hfig);
                hCopied(h).Position = positionUse;
                delete(hsubnew)
                set(gca,'FontSize',16);
            end
            hfig.Color = 'w';
            linkaxes(hCopied,'x');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.selectdataTab = uitab(app.TabGroup);
            app.selectdataTab.Title = 'select data';
            app.loaddataButton = uibutton(app.selectdataTab, 'push');
            app.loaddataButton.ButtonPushedFcn = createCallbackFcn(app, @loaddataButtonPushed, true);
            app.loaddataButton.Text = 'load data';
            app.saveidxsButton = uibutton(app.selectdataTab, 'push');
            app.saveidxsButton.Text = 'save idxs';
            app.dataplotsPanel = uipanel(app.selectdataTab);
            app.dataplotsPanel.Title = 'data plots';
            app.loadidxsButton = uibutton(app.selectdataTab, 'push');
            app.loadidxsButton.Text = 'load idxs';
            app.montagefileDropDownLabel = uilabel(app.selectdataTab);
            app.montagefileDropDownLabel.Text = 'montage file';
            app.montagefileDropDown = uidropdown(app.selectdataTab);
            app.zoomButton = uibutton(app.selectdataTab, 'push');
            app.zoomButton.ButtonPushedFcn = createCallbackFcn(app, @zoomButtonPushed, true);
            app.zoomButton.Text = 'zoom';
            app.zoomcurrentButton = uibutton(app.selectdataTab, 'push');
            app.zoomcurrentButton.ButtonPushedFcn = createCallbackFcn(app, @zoomcurrentButtonPushed, true);
            app.zoomcurrentButton.Text = 'zoom current';
            app.plotpsdsButton = uibutton(app.selectdataTab, 'push');
            app.plotpsdsButton.ButtonPushedFcn = createCallbackFcn(app, @plotpsdsButtonPushed, true);
            app.plotpsdsButton.Text = 'plot psds';
            app.exportrawdataButton = uibutton(app.selectdataTab, 'push');
            app.exportrawdataButton.ButtonPushedFcn = createCallbackFcn(app, @exportrawdataButtonPushed, true);
            app.exportrawdataButton.Text = 'export raw data';
            app.popoutButton_4 = uibutton(app.selectdataTab, 'push');
            app.popoutButton_4.ButtonPushedFcn = createCallbackFcn(app, @popoutButton_4Pushed, true);
            app.popoutButton_4.Text = 'pop out';
            app.PSDSTNTab = uitab(app.TabGroup);
            app.PSDSTNTab.Title = 'PSD STN';
            app.PlotdataPanelSTN = uipanel(app.PSDSTNTab);
            app.PlotdataPanelSTN.Title = 'Plot data';
            app.montageListBox_2Label = uilabel(app.PSDSTNTab);
            app.montageListBox_2Label.Text = 'montage';
            app.psdMontageSelector = uilistbox(app.PSDSTNTab);
            app.psdMontageSelector.ValueChangedFcn = createCallbackFcn(app, @psdMontageSelectorValueChanged, true);
            app.saveFigurePsdSTN = uibutton(app.PSDSTNTab, 'push');
            app.saveFigurePsdSTN.ButtonPushedFcn = createCallbackFcn(app, @saveFigurePsdSTNPushed, true);
            app.saveFigurePsdSTN.Text = 'save figure';
            app.EditFieldLabel = uilabel(app.PSDSTNTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.PSDSTNTab, 'text');
            app.EditField.Value = 'stn_montage';
            app.xminEditFieldLabel = uilabel(app.PSDSTNTab);
            app.xminEditFieldLabel.Text = 'xmin';
            app.xminEditField = uieditfield(app.PSDSTNTab, 'numeric');
            app.xminEditField.ValueChangedFcn = createCallbackFcn(app, @xminEditFieldValueChanged, true);
            app.xmaxEditFieldLabel = uilabel(app.PSDSTNTab);
            app.xmaxEditFieldLabel.Text = 'xmax';
            app.xmaxEditField = uieditfield(app.PSDSTNTab, 'numeric');
            app.xmaxEditField.ValueChangedFcn = createCallbackFcn(app, @xmaxEditFieldValueChanged, true);
            app.popOutPSDstn = uibutton(app.PSDSTNTab, 'push');
            app.popOutPSDstn.ButtonPushedFcn = createCallbackFcn(app, @popOutPSDstnPushed, true);
            app.popOutPSDstn.Text = 'pop out';
            app.PSDM1Tab = uitab(app.TabGroup);
            app.PSDM1Tab.Title = 'PSD M1';
            app.PlotdataPanelM1 = uipanel(app.PSDM1Tab);
            app.PlotdataPanelM1.Title = 'Plot data';
            app.montageListBoxLabel = uilabel(app.PSDM1Tab);
            app.montageListBoxLabel.Text = 'montage';
            app.m1MontageSelector = uilistbox(app.PSDM1Tab);
            app.m1MontageSelector.ValueChangedFcn = createCallbackFcn(app, @m1MontageSelectorValueChanged, true);
            app.saveFigurePSDM1 = uibutton(app.PSDM1Tab, 'push');
            app.saveFigurePSDM1.ButtonPushedFcn = createCallbackFcn(app, @saveFigurePSDM1Pushed, true);
            app.saveFigurePSDM1.Text = 'save figure';
            app.EditField_2Label = uilabel(app.PSDM1Tab);
            app.EditField_2Label.Text = 'Edit Field';
            app.EditField_2 = uieditfield(app.PSDM1Tab, 'text');
            app.EditField_2.Value = 'm1_montage';
            app.xminEditField_2Label = uilabel(app.PSDM1Tab);
            app.xminEditField_2Label.Text = 'xmin';
            app.xminEditField_2 = uieditfield(app.PSDM1Tab, 'numeric');
            app.xminEditField_2.ValueChangedFcn = createCallbackFcn(app, @xminEditField_2ValueChanged, true);
            app.xmaxEditField_2Label = uilabel(app.PSDM1Tab);
            app.xmaxEditField_2Label.Text = 'xmax';
            app.xmaxEditField_2 = uieditfield(app.PSDM1Tab, 'numeric');
            app.xmaxEditField_2.ValueChangedFcn = createCallbackFcn(app, @xmaxEditField_2ValueChanged, true);
            app.popOutPSDM1 = uibutton(app.PSDM1Tab, 'push');
            app.popOutPSDM1.ButtonPushedFcn = createCallbackFcn(app, @popOutPSDM1Pushed, true);
            app.popOutPSDM1.Text = 'pop out';
            app.CoherenceTab = uitab(app.TabGroup);
            app.CoherenceTab.Title = 'Coherence';
            app.hCoherenceDataPanel = uipanel(app.CoherenceTab);
            app.hCoherenceDataPanel.Title = 'Coherence Data Plot';
            app.hSaveFigureCoherence = uibutton(app.CoherenceTab, 'push');
            app.hSaveFigureCoherence.ButtonPushedFcn = createCallbackFcn(app, @hSaveFigureCoherencePushed, true);
            app.hSaveFigureCoherence.Text = 'save figure';
            app.configfilesLabel = uilabel(app.CoherenceTab);
            app.configfilesLabel.Text = 'config files';
            app.hCoherenceMontageSelecor = uilistbox(app.CoherenceTab);
            app.hCoherenceMontageSelecor.ValueChangedFcn = createCallbackFcn(app, @hCoherenceMontageSelecorValueChanged, true);
            app.EditField_3Label = uilabel(app.CoherenceTab);
            app.EditField_3Label.Text = 'Edit Field';
            app.hCoherenceFigreName = uieditfield(app.CoherenceTab, 'text');
            app.hCoherenceFigreName.Value = 'coherence_mon';
            app.hCoherencePopOut = uibutton(app.CoherenceTab, 'push');
            app.hCoherencePopOut.ButtonPushedFcn = createCallbackFcn(app, @hCoherencePopOutButtonPushed, true);
            app.hCoherencePopOut.Text = 'pop out';
            app.hPlotcoherenceButton = uibutton(app.CoherenceTab, 'push');
            app.hPlotcoherenceButton.ButtonPushedFcn = createCallbackFcn(app, @hPlotcoherenceButtonPushed, true);
            app.hPlotcoherenceButton.Text = 'plot coherence';
            app.PACwithinGPSTNleadTab = uitab(app.TabGroup);
            app.PACwithinGPSTNleadTab.Title = 'PAC within GP/STN lead';
            app.PlotdataPanelM1_3 = uipanel(app.PACwithinGPSTNleadTab);
            app.PlotdataPanelM1_3.Title = 'Plot data';
            app.LFPcontactLabel = uilabel(app.PACwithinGPSTNleadTab);
            app.LFPcontactLabel.Text = 'LFP contact';
            app.m1MontageSelector_3 = uilistbox(app.PACwithinGPSTNleadTab);
            app.m1contactLabel = uilabel(app.PACwithinGPSTNleadTab);
            app.m1contactLabel.Text = 'm1 contact';
            app.m1MontageSelector_4 = uilistbox(app.PACwithinGPSTNleadTab);
            app.plotButton = uibutton(app.PACwithinGPSTNleadTab, 'push');
            app.plotButton.Text = 'plot';
            app.popoutButton_5 = uibutton(app.PACwithinGPSTNleadTab, 'push');
            app.popoutButton_5.Text = 'pop out';
            app.saveButton = uibutton(app.PACwithinGPSTNleadTab, 'push');
            app.saveButton.Text = 'save';
            app.PACwnM1Tab = uitab(app.TabGroup);
            app.PACwnM1Tab.Title = 'PAC w/n M1';
            app.PACbtwndeepecogTab = uitab(app.TabGroup);
            app.PACbtwndeepecogTab.Title = 'PAC btwn deep/ecog';
            app.PAC1000hzTab = uitab(app.TabGroup);
            app.PAC1000hzTab.Title = 'PAC 1000hz';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plot_montage_data(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end